classdef log_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LogFileLabel          matlab.ui.control.Label
        SelectLogFileButton   matlab.ui.control.Button
        GenerateGraphsButton  matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes1               matlab.ui.control.UIAxes
        UIAxes6               matlab.ui.control.UIAxes
        UIAxes5               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
    end

    % Properties that correspond to member variables
    properties (Access = private)
        timestamp;      % mm:ss.SSS
        target_pos;     % deg
        actual_pos;     % deg
        actual_torque;  % Nm
        pump_state;     % bool; CURRENTLY BROKEN
        camera_pos;     % deg
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectLogFileButton
        function SelectLogFileButtonPushed(app, event)
            % Save off full path to log file
            [file, location] = uigetfile('*.csv');
            if file == 0
                warndlg('Invalid input file!')
                return;
            end
            logfilepath = fullfile(location,file);

            % Update UI elements
            app.LogFileLabel.Text = file;
            app.GenerateGraphsButton.Enable = 'on';

            % Read log file contents
            log = readtable(logfilepath);

            % - Relative Timestamp
            app.timestamp = datetime(log.Time, 'InputFormat','mm:ss.SSS');
            app.timestamp = app.timestamp - app.timestamp(1);  % adjust timestamps relative to start of log
            app.timestamp.Format = 'mm:ss.SSS';  % subtracting datetime results in duration, so reformat as datetime

            % - HEBI Actuators
            app.target_pos = [log.TP_Roll_deg_ log.TP_Pitch_deg_ log.TP_J1_deg_ log.TP_J2_deg_ log.TP_J3_deg_];
            app.actual_pos = [log.PP_Roll_deg_ log.PP_Pitch_deg_ log.PP_J1_deg_ log.PP_J2_deg_ log.PP_J3_deg_];
            app.actual_torque = [log.PT_Roll_Nm_ log.PT_Pitch_Nm_ log.PT_J1_Nm_ log.PT_J2_Nm_  log.PT_J3_Nm_];

            % - Water Pumps (SerialWater)
            app.pump_state = [log.A_IN log.B_IN log.A_OUT log.B_OUT];

            % - Camera Actuators (SerialServo)
            app.camera_pos = [log.TP_CamBase_deg_ log.TP_CamPan_deg_ log.TP_CamTilt_deg_];
        end

        % Button pushed function: GenerateGraphsButton
        function GenerateGraphsButtonPushed(app, event)
            %% Configure Plots

            % Declare common variables
            % - Timestamp (start: 0, step: 30s, end: latest time rounded to 30s)
            % Convert the original duration to seconds
            latest_time_s = seconds(app.timestamp(end));
            time_end = ceil(latest_time_s / 30) * 30;
            time_range = 0:30:time_end;
            % - Position (start: -180 deg, step: 30 deg, end: 180 deg)
            pos_range = -180:30:180;
            % - Torque (start: -40 Nm, step: 10 Nm, end: 40 Nm)
            torque_range = -40:10:40;
            % - Pump State (start: 0 aka "False", step: 1, end: 1 aka "True")
            bool_range = 0:1:1;

            % Actuator position (target) vs. time
            app.UIAxes1.XTick = time_range;
            app.UIAxes1.YTick = pos_range;

            % Actuator position (actual) vs. time
            app.UIAxes2.XTick = time_range;
            app.UIAxes2.YTick = pos_range;

            % Actuator torque (actual) vs. time
            app.UIAxes3.XTick = time_range;
            app.UIAxes3.YTick = torque_range;

            % Pump state vs. time
            app.UIAxes4.XTick = time_range;
            app.UIAxes4.YTick = bool_range;

            % Camera position vs. time
            app.UIAxes5.XTick = time_range;
            app.UIAxes5.YTick = pos_range;

            % Actuator positions vs. time
            app.UIAxes6.XTick = time_range;
            app.UIAxes6.YTick = pos_range;

            %% Plot Data

            % Actuator position (target) vs. time
            plot(app.UIAxes1, seconds(app.timestamp), app.target_pos);
            title(app.UIAxes1, 'Actuator Target Position over Time');
            legend(app.UIAxes1, 'Roll', 'Pitch', 'J1', 'J2', 'J3');
            xlabel(app.UIAxes1, 'Time (s)');
            ylabel(app.UIAxes1, 'Position (deg)');

            % Actuator position (actual) vs. time
            plot(app.UIAxes2, seconds(app.timestamp), app.actual_pos);
            title(app.UIAxes2, 'Actuator Actual Position over Time');
            legend(app.UIAxes2, 'Roll', 'Pitch', 'J1', 'J2', 'J3');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Position (deg)');

            % Actuator torque (actual) vs. time
            plot(app.UIAxes3, seconds(app.timestamp), app.actual_torque);
            title(app.UIAxes3, 'Actuator Actual Torque over Time');
            legend(app.UIAxes3, 'Roll', 'Pitch', 'J1', 'J2', 'J3');
            xlabel(app.UIAxes3, 'Time (s)');
            ylabel(app.UIAxes3, 'Torque (Nm)');

            % Pump state vs. time
            plot(app.UIAxes4, seconds(app.timestamp), app.pump_state);
            title(app.UIAxes4, 'Pump State over Time');
            legend(app.UIAxes4, 'A_IN', 'B_IN', 'A_OUT', 'B_OUT');
            xlabel(app.UIAxes4, 'Time (s)');
            ylabel(app.UIAxes4, 'Activity (on/off)');

            % Camera position vs. time
            plot(app.UIAxes5, seconds(app.timestamp), app.camera_pos);
            title(app.UIAxes5, 'Camera Actual Position over Time');
            legend(app.UIAxes5, 'Base (correction)', 'Pan', 'Tilt');
            xlabel(app.UIAxes5, 'Time (s)');
            ylabel(app.UIAxes5, 'Position (deg)');

            % Actuator positions vs. time
            hold(app.UIAxes6, 'on');
            color_order = get(app.UIAxes6, 'ColorOrder');  % get default color order
            for i = 1:size(app.target_pos, 2)
                plot(app.UIAxes6, seconds(app.timestamp), app.target_pos(:,i), '--', 'Color', color_order(i, :));
                plot(app.UIAxes6, seconds(app.timestamp), app.actual_pos(:,i), '-', 'Color', color_order(i, :));
            end
            hold(app.UIAxes6, 'off');
            title(app.UIAxes6, 'Actuator Target vs. Actual Position over Time');
            legend(app.UIAxes6, 'Roll (target)', 'Roll (actual)', ...
                                'Pitch (target)', 'Pitch (actual)', ...
                                'J1 (target)', 'J1 (actual)', ...
                                'J2 (target)', 'J2 (actual)', ...
                                'J3 (target)', 'J3 (actual)');
            xlabel(app.UIAxes6, 'Time (s)');
            ylabel(app.UIAxes6, 'Position (deg)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GenerateGraphsButton = uibutton(app.GridLayout, 'push');
            app.GenerateGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphsButtonPushed, true);
            app.GenerateGraphsButton.Enable = 'off';
            app.GenerateGraphsButton.Text = 'Generate Graphs';
            app.SelectLogFileButton = uibutton(app.GridLayout, 'push');
            app.SelectLogFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLogFileButtonPushed, true);
            app.SelectLogFileButton.Text = 'Select Log File';
            app.LogFileLabel = uilabel(app.GridLayout);
            app.LogFileLabel.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = log_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end